classdef RelativeBaselineChangeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LogTextArea                     matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        FrameSelectionTab               matlab.ui.container.Tab
        FrameSelectionGrid              matlab.ui.container.GridLayout
        InstructionsLabel               matlab.ui.control.Label
        Timepoint2EditField             matlab.ui.control.EditField
        Timepoint2EditFieldLabel        matlab.ui.control.Label
        Timepoint1EditField             matlab.ui.control.EditField
        Timepoint1EditFieldLabel        matlab.ui.control.Label
        SelectAndLoadButton             matlab.ui.control.Button
        Timepoint1Axes                  matlab.ui.control.UIAxes
        Timepoint2Axes                  matlab.ui.control.UIAxes
        AnalysisSettingsoptionalTab     matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        RecalculateRadiiCheckBox        matlab.ui.control.CheckBox
        ApplyManualButton               matlab.ui.control.Button
        ManualInnerEditField            matlab.ui.control.NumericEditField
        InnerRadiusLabel                matlab.ui.control.Label
        ManualOuterEditField            matlab.ui.control.NumericEditField
        ManualOuterFactorLabel          matlab.ui.control.Label
        ManualMethodDropDown            matlab.ui.control.DropDown
        ManualBackgroundSubtractionMethodLabel  matlab.ui.control.Label
        ORLabel                         matlab.ui.control.Label
        VisualizeFailedCellsButton      matlab.ui.control.Button
        SettingsResultsTable            matlab.ui.control.Table
        ApplyandSaveButton              matlab.ui.control.Button
        SettingsInstructionsLabel       matlab.ui.control.Label
        FindOptimalSettingsButton       matlab.ui.control.Button
        AnalysisPipelineTab             matlab.ui.container.Tab
        AnalysisGrid                    matlab.ui.container.GridLayout
        AnalysisModeGroup               matlab.ui.container.ButtonGroup
        ExperimentalModeButton          matlab.ui.control.RadioButton
        ControlModeButton               matlab.ui.control.RadioButton
        ExperimentalAnalysisPanel       matlab.ui.container.Panel
        ExperimentalAnalysisGrid        matlab.ui.container.GridLayout
        ExpTimepoint2SubfolderNameEditField  matlab.ui.control.EditField
        Timepoint2SubfolderNameEditField_2Label  matlab.ui.control.Label
        ExpTimepoint1SubfolderNameEditField  matlab.ui.control.EditField
        Timepoint1SubfolderNameEditField_2Label  matlab.ui.control.Label
        ControlThresholdsField          matlab.ui.control.EditField
        ExperimentalFolderField         matlab.ui.control.EditField
        RunExperimentalButton           matlab.ui.control.Button
        LoadControlThresholdsButton     matlab.ui.control.Button
        SelectExperimentalFolderButton  matlab.ui.control.Button
        ExperimentalDistributionAxes    matlab.ui.control.UIAxes
        ExperimentalSpatialAxes         matlab.ui.control.UIAxes
        ControlAnalysisPanel            matlab.ui.container.Panel
        ControlAnalysisGrid             matlab.ui.container.GridLayout
        Timepoint1SubfolderNameEditField  matlab.ui.control.EditField
        Timepoint1SubfolderNameEditFieldLabel  matlab.ui.control.Label
        Timepoint2SubfolderNameEditField  matlab.ui.control.EditField
        Timepoint2SubfolderNameEditFieldLabel  matlab.ui.control.Label
        RunControlButton                matlab.ui.control.Button
        ControlsListBox                 matlab.ui.control.ListBox
        ControlsListBoxLabel            matlab.ui.control.Label
        SelectControlFoldersButton      matlab.ui.control.Button
        ControlsDistributionAxes        matlab.ui.control.UIAxes
        ControlSpatialExampleAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Properties for Tab 1
        FolderPath1          % Path to the first timepoint folder
        FolderPath2          % Path to the second timepoint folder
        Folder1Name_short    % Just the name of the folder (e.g., '0minus')
        Folder2Name_short    % Just the name of the folder (e.g., '0plus')
        FData1
        FData2
        CursorLine1 % Handle for the line on the left axes
        CursorLine2 % Handle for the line on the right axes
        SelectionState char % Will be 'left', 'right', or inactive
        FrameBefore % Store the frame number from the first click

        % Properties for Settings Tab 2
        AnalysisSettings struct
        OptimizationResults table
        TempMatchedData struct

        % Properties for Tab 3
        ControlFolderPaths cell
        ExperimentalFolderPath
        ThresholdData struct
    end
    
    methods (Access = private)
        
        function startupFcn(app)

            logMessages = {'Welcome!'};
            
            app.AnalysisModeGroup.SelectedObject = app.ExperimentalModeButton;
            
            defaultSettings = struct(...
                'Method', 'Median', 'InnerFactor', 1.8, 'OuterFactor', 2.0, 'RecalculateRadii', false);
            app.AnalysisSettings = defaultSettings;
            
            settingsFile = fullfile(app.getAppPath(), 'CalciumBaselineAnalysisSettings.mat');
            if exist(settingsFile, 'file')
                try
                    loadedVars = load(settingsFile); 
                    if isfield(loadedVars, 'savedSettings') && isstruct(loadedVars.savedSettings)
                        sourceStruct = loadedVars.savedSettings;
                        logMessages{end+1} = 'Found ''savedSettings'' struct. Loading...';
                    else
                        sourceStruct = loadedVars;
                        logMessages{end+1} = 'No ''savedSettings'' struct found. Checking legacy format...';
                    end
                    if isfield(sourceStruct, 'Method'), app.AnalysisSettings.Method = sourceStruct.Method; end
                    if isfield(sourceStruct, 'InnerFactor'), app.AnalysisSettings.InnerFactor = sourceStruct.InnerFactor; end
                    if isfield(sourceStruct, 'OuterFactor'), app.AnalysisSettings.OuterFactor = sourceStruct.OuterFactor; end
                    if isfield(sourceStruct, 'RecalculateRadii'), app.AnalysisSettings.RecalculateRadii = sourceStruct.RecalculateRadii; end
                    logMessages{end+1} = 'Settings successfully updated from file.';
                catch ME
                    logMessages{end+1} = ['Warning: Could not load settings file. Using defaults. Error: ' ME.message];
                end
            else
                logMessages{end+1} = 'No settings file found. Using default settings.';
            end
        
            logMessages{end+1} = sprintf(...
                'Current settings: Method=%s, Inner=%.2f, Outer=%.2f, RecalcRadii=%d', ... 
                app.AnalysisSettings.Method, app.AnalysisSettings.InnerFactor, ...
                app.AnalysisSettings.OuterFactor, app.AnalysisSettings.RecalculateRadii);

            % Set the value. The font is already correct.
            app.LogTextArea.Value = logMessages;
        end
        
        function outputFolderPath = createOutputFolder(app, sampleFolderPath)
            % This function creates a dedicated output folder for a given sample.
            % e.g., if sampleFolderPath is 'C:\Data\Sample1', it creates and
            % returns 'C:\Data\Sample1\BaselineChange'.
            
            outputFolderName = 'BaselineChange';
            outputFolderPath = fullfile(sampleFolderPath, outputFolderName);
            
            % Check if the folder already exists, if not, create it.
            if ~exist(outputFolderPath, 'dir')
                mkdir(outputFolderPath);
                app.LogTextArea.Value{end+1} = ['    Created output folder: ' outputFolderName];
            end
        end

        function app_path = getAppPath(app)
            % This function finds the directory where the .mlapp file is located.
            try
                full_path = mfilename('fullpath');
                % fileparts will return the path to the folder containing the file
                app_path = fileparts(full_path);
            catch
                % Fallback to the current working directory if something goes wrong
                app_path = pwd;
            end
        end

        function resultsTable = optimizeBackgroundSettings(app, image1, image2, centroids1, radii1, centroids2, radii2, bgMethod)
            %optimizeBackgroundSettings Iterates through settings to find optimal parameters
            
            % --- 1. Define the Search Space ---
            innerFactors = 1.2:0.1:3.0;
            outerFactorSteps = 0.2:0.1:1.5;
            combinations = [];
            for inner = innerFactors
                for step = outerFactorSteps
                    outer = inner + step;
                    combinations = [combinations; inner, outer];
                end
            end
            numCombinations = size(combinations, 1);
            
            % --- 2. Create local copies for parallel processing ---
            % The radii passed in here are now the FINAL ones to be used.
            local_image1 = image1;
            local_image2 = image2;
            local_centroids1 = centroids1;
            local_centroids2 = centroids2;
            local_radii1 = radii1;
            local_radii2 = radii2;
            
            % Pre-allocate results arrays
            qualityScores = zeros(numCombinations, 1);
            innerFactors = zeros(numCombinations, 1);
            outerFactors = zeros(numCombinations, 1);
            methods = cell(numCombinations, 1);
            validCounts = zeros(numCombinations, 1);
            
            % --- 3. Run Optimization Loop ---
            parfor i = 1:numCombinations
                bgInnerFactor = combinations(i, 1);
                bgOuterFactor = combinations(i, 2);
                
                numCells = size(local_centroids1, 1);
                intensity1 = zeros(numCells, 1);
                intensity2 = zeros(numCells, 1);
                
                for c = 1:numCells
                    x = local_centroids1(c,1); 
                    y = local_centroids1(c,2); 
                    r = local_radii1(c); % Directly use the provided radius
                    
                    x2 = local_centroids2(c,1);  
                    y2 = local_centroids2(c,2);  
                    r2 = local_radii2(c); % Directly use the provided radius
                    
                    % --- The logic for recalculation is GONE from here ---
                    % This makes the loop much cleaner and faster.
                    
                    [intensity1_cell, ~] = calculateSingleCellIntensity(...
                        local_image1, x, y, r, bgInnerFactor, bgOuterFactor, bgMethod);
                    
                    [intensity2_cell, ~] = calculateSingleCellIntensity(...
                        local_image2, x2, y2, r2, bgInnerFactor, bgOuterFactor, bgMethod);
        
                    intensity1(c) = intensity1_cell;
                    intensity2(c) = intensity2_cell;
                end
                
                valid_cells = (intensity1 > 0) & (intensity2 > 0) & ~isnan(intensity1) & ~isnan(intensity2);
                validCount = sum(valid_cells);
                qualityScore = (validCount / numCells) * 100;
                
                innerFactors(i) = bgInnerFactor;
                outerFactors(i) = bgOuterFactor;
                methods{i} = bgMethod;
                qualityScores(i) = qualityScore;
                validCounts(i) = validCount;
            end
            
            if numCombinations >= 1
                app.LogTextArea.Value{end+1} = sprintf(...
                    '[Optimization] Combo 1 (%.1f,%.1f): %d/%d cells valid at BOTH timepoints', ...
                    combinations(1,1), combinations(1,2), validCounts(1), size(local_centroids1, 1));
            end
            
            resultsTable = table(...
                innerFactors, outerFactors, methods, qualityScores, ...
                'VariableNames', {'InnerFactor', 'OuterFactor', 'Method', 'QualityScore_percent'});
            
            resultsTable = sortrows(resultsTable, 'QualityScore_percent', 'descend');
       
        end

        function [IntensityChange, radii_final_1, radii_final_2] = calculateIntensityChange(app, MatchedCells)
            % Initialize default outputs
            IntensityChange = struct();
            radii_final_1 = [];
            radii_final_2 = [];
            
            try
                % --- UNPACK DATA AND SETTINGS (Unchanged) ---
                app.LogTextArea.Value{end+1} = '--- INTENSITY CALCULATION ---';
                matchedCentroids1 = MatchedCells.matchedCentroidCoordinates_0minus;
                matchedRadii1 = MatchedCells.matchedRadii_0minus;
                matchedCentroids2 = MatchedCells.matchedCentroidCoordinates_timepoint2;
                matchedRadii2 = MatchedCells.matchedRadii_timepoint2;
                image1_raw = MatchedCells.GCaMP_Image1;
                image2_raw = MatchedCells.GCaMP_Image2;
                settings = app.AnalysisSettings;
                recalculateRadii = settings.RecalculateRadii;
                numMatched = size(matchedCentroids1, 1);
                app.LogTextArea.Value{end+1} = sprintf('Matched cells: %d. Settings: Method=%s, Inner=%.2f, Outer=%.2f, Recalc=%d', ...
                    numMatched, settings.Method, settings.InnerFactor, settings.OuterFactor, recalculateRadii);
                se = strel('disk', 15);
                image1 = imtophat(im2single(image1_raw), se);
                image2 = imtophat(im2single(image2_raw), se);
                intensity1 = zeros(numMatched, 1, 'single');
                intensity2 = zeros(numMatched, 1, 'single');
                radii_final_1 = zeros(numMatched, 1);
                radii_final_2 = zeros(numMatched, 1);
        
                % --- RADIUS & INTENSITY CALCULATION LOOP (Unchanged) ---
                for i = 1:numMatched
                    r1_orig = matchedRadii1(i); r2_orig = matchedRadii2(i);
                    r1_final = r1_orig; r2_final = r2_orig;
                    if recalculateRadii
                        x1 = round(matchedCentroids1(i,1)); y1 = round(matchedCentroids1(i,2));
                        patch_half_width = ceil(r1_orig * 2.5);
                        ymin = max(1, y1 - patch_half_width); ymax = min(size(image1,1), y1 + patch_half_width);
                        xmin = max(1, x1 - patch_half_width); xmax = min(size(image1,2), x1 + patch_half_width);
                        patch1 = image1(ymin:ymax, xmin:xmax);
                        if ~isempty(patch1), r1_final = findBaselineRadius(patch1, r1_orig); end
                        
                        x2 = round(matchedCentroids2(i,1)); y2 = round(matchedCentroids2(i,2));
                        patch_half_width2 = ceil(r2_orig * 2.5);
                        ymin2 = max(1, y2 - patch_half_width2); ymax2 = min(size(image2,1), y2 + patch_half_width2);
                        xmin2 = max(1, x2 - patch_half_width2); xmax2 = min(size(image2,2), x2 + patch_half_width2);
                        patch2 = image2(ymin2:ymax2, xmin2:xmax2);
                        if ~isempty(patch2), r2_final = findBaselineRadius(patch2, r2_orig); end
                    end
                    radii_final_1(i) = r1_final; radii_final_2(i) = r2_final;
                    [intensity1(i), ~] = calculateSingleCellIntensity(image1, matchedCentroids1(i,1), matchedCentroids1(i,2), r1_final, settings.InnerFactor, settings.OuterFactor, settings.Method);
                    [intensity2(i), ~] = calculateSingleCellIntensity(image2, matchedCentroids2(i,1), matchedCentroids2(i,2), r2_final, settings.InnerFactor, settings.OuterFactor, settings.Method);
                end
                
                % --- NEW ROBUST VALIDATION & FOLD CHANGE LOGIC ---
                
                % Step 1: Apply pseudocount.
                pseudocount = 1e-6; 
                intensity1_reg = intensity1 + pseudocount;
                intensity2_reg = intensity2 + pseudocount;
        
                % Step 2: Initialize arrays to NaN and Define the final validity mask based on regularized data.
                cell_FoldChange = nan(numMatched,1);
                cell_logFoldChange = nan(numMatched,1);

                log_valid_mask = (intensity1_reg > 0) & (intensity2_reg > 0);
                valid_mask = intensity1_reg > 0;
        
                num_valid_measurements = sum(log_valid_mask);
                app.LogTextArea.Value{end+1} = sprintf('    Valid pairs post-regularization: %d/%d (%.1f%%)', ...
                    num_valid_measurements, numMatched, (num_valid_measurements/numMatched)*100);
        
                % Step 3: Create the fold change ratio only for valid cells.
                valid_indices = find(valid_mask);
                % ONLY calculate the ratio for the valid cells. This prevents division by zero or negative numbers.
                cell_FoldChange(valid_indices) = intensity2_reg(valid_indices) ./ intensity1_reg(valid_indices);
                
                % Step 4: Calculate the log2. Since we pre-filtered, all inputs to log2 are guaranteed to be positive.
                % This prevents complex numbers from ever being generated.
                log_valid_indices = find(log_valid_mask);
                cell_logFoldChange(log_valid_indices) = log2(cell_FoldChange(log_valid_indices)); % log2(NaN) is NaN, which is the desired behavior.
                
                % Step 5: Create the list of valid cell indices.
                original_indices_matrix = MatchedCells.matchedCellNumbers;
                valid_cell_indices = original_indices_matrix(log_valid_mask, :);
        
                % Step 6: Package all results.
                IntensityChange = struct(...
                    'cell_FoldChange',cell_FoldChange,...
                    'cell_logFoldChange', cell_logFoldChange, ...
                    'mean_logFoldChange', mean(cell_logFoldChange, 'omitnan'), ...
                    'time1_intensities',intensity1,...
                    'time2_intensities',intensity2,...
                    'valid_log2_cell_indices', valid_cell_indices, ...
                    'image1_for_plotting', image1, ...
                    'centroids1_for_plotting', matchedCentroids1, ...
                    'invalidMask_for_plotting', ~log_valid_mask);
                
            catch ME
                errMsg = sprintf('Error in intensity calculation:\n%s', getReport(ME, 'extended', 'hyperlinks', 'off'));
                uialert(app.UIFigure, errMsg, 'Critical Calculation Error');
                IntensityChange = struct();
            end
        end
           
        function createExcludedCellsFigure(app, fig, image, centroids, invalidMask)
            % This function now populates a PRE-EXISTING figure handle
            % It MUST accept 'fig' as the first argument after 'app'
            ax = axes(fig);
            imshow(image, 'Parent', ax);
            hold(ax, 'on');
            
            % Plot valid cells in green
            validIdx = find(~invalidMask);
            if ~isempty(validIdx)
                scatter(ax, centroids(validIdx,1), centroids(validIdx,2), ...
                    'g', 'filled', 'MarkerFaceAlpha', 0.5);
            end
            
            % Plot invalid cells in red
            invalidIdx = find(invalidMask);
            if ~isempty(invalidIdx)
                scatter(ax, centroids(invalidIdx,1), centroids(invalidIdx,2), ...
                    'r', 'filled', 'MarkerFaceAlpha', 0.5);
            end
            
            title(ax, 'Spatial Distribution of Excluded Cells');
            legend(ax, 'Valid T1', 'Excluded', 'Location', 'best');
            hold(ax, 'off');
        end

        % HELPER 1: Handles mouse movement. This version is correct.
        function updateCursorLines(app)
            % Corrected axes names
            axes_to_check = [app.Timepoint1Axes, app.Timepoint2Axes];
            fig_pt = app.UIFigure.CurrentPoint;
            is_over_an_axis = false;
            for ax = axes_to_check
                ax_pos = getpixelposition(ax);
                is_inside = fig_pt(1) >= ax_pos(1) && fig_pt(1) <= ax_pos(1) + ax_pos(3) && ...
                            fig_pt(2) >= ax_pos(2) && fig_pt(2) <= ax_pos(2) + ax_pos(4);
                if is_inside
                    is_over_an_axis = true;
                    x_coord = ax.CurrentPoint(1, 1);
                    if isvalid(app.CursorLine1); set(app.CursorLine1, 'XData', [x_coord, x_coord]); end
                    if isvalid(app.CursorLine2); set(app.CursorLine2, 'XData', [x_coord, x_coord]); end
                    break; 
                end
            end
            if ~is_over_an_axis
                if isvalid(app.CursorLine1); set(app.CursorLine1, 'XData', [NaN, NaN]); end
                if isvalid(app.CursorLine2); set(app.CursorLine2, 'XData', [NaN, NaN]); end
            end
        end
    
        % HELPER 2: Handles mouse clicks.
        function processClick(app)
            if strcmp(app.SelectionState, 'inactive')
                % Explicitly remove cursor lines
                delete(app.CursorLine1);
                delete(app.CursorLine2);
                return; 
            end

            if strcmp(app.SelectionState, 'inactive'); return; end
            
            target_ax = [];
            if strcmp(app.SelectionState, 'left')
                target_ax = app.Timepoint1Axes; % Corrected name
            elseif strcmp(app.SelectionState, 'right')
                target_ax = app.Timepoint2Axes; % Corrected name
            end
            
            if isempty(target_ax); return; end % Safety check
    
            ax_pos = getpixelposition(target_ax);
            fig_pt = app.UIFigure.CurrentPoint;
            if ~(fig_pt(1) >= ax_pos(1) && fig_pt(1) <= ax_pos(1) + ax_pos(3) && ...
                 fig_pt(2) >= ax_pos(2) && fig_pt(2) <= ax_pos(2) + ax_pos(4));
                return;
            end
            
            if strcmp(app.SelectionState, 'left')
                x_axis1 = app.FData1(1, :);
                xsel1 = target_ax.CurrentPoint(1, 1);
                [~, frame_idx] = min(abs(x_axis1 - xsel1));
                app.FrameBefore = frame_idx;
                
                hold(target_ax, 'on');
                xline(target_ax, x_axis1(frame_idx), 'r--', 'LineWidth', 1.5, 'Label', ['F ' num2str(frame_idx)]);
                hold(target_ax, 'off');
                
                app.SelectionState = 'right';
                app.LogTextArea.Value = {'Left frame selected. Now click on the RIGHT plot.'};
                app.InstructionsLabel.Text = 'INSTRUCTIONS: Click on the RIGHT plot to select a frame.'; % Corrected name
                
            elseif strcmp(app.SelectionState, 'right')
                x_axis2 = app.FData2(1, :);
                xsel2 = target_ax.CurrentPoint(1, 1);
                [~, frame_after] = min(abs(x_axis2 - xsel2));
                
                hold(target_ax, 'on');
                xline(target_ax, x_axis2(frame_after), 'r--', 'LineWidth', 1.5, 'Label', ['F ' num2str(frame_after)]);
                hold(target_ax, 'off');
                
                app.SelectionState = 'inactive';
                app.UIFigure.WindowButtonMotionFcn = '';
                app.UIFigure.WindowButtonDownFcn = '';
                delete(app.CursorLine1);
                delete(app.CursorLine2);
    
                frame_before = app.FrameBefore;
                msg = sprintf(['ACTION REQUIRED:\n\n' ...
                               '1. Save frame %d as "beforeraw.tif" in folder:\n%s\n\n' ...
                               '2. Save frame %d as "afterraw.tif" in folder:\n%s\n\n' ...
                               'Once images are saved, proceed to next tab.'], ...
                               frame_before, app.FolderPath1, frame_after, app.FolderPath2);
                app.InstructionsLabel.Text = msg; % Corrected name
                app.LogTextArea.Value = {'Frame selection complete.'};
            end
        end
        
        function plotSpatialDistribution(app, target_axes, MatchedCells, IntensityChange, threshold_high, threshold_low, plotTitle)
            % This version accepts an optional title argument for robust plotting.
            cla(target_axes);
            
            matchedCentroids = MatchedCells.matchedCentroidCoordinates_0minus;
            isIncrease = IntensityChange.cell_logFoldChange > threshold_high;
            isDecrease = IntensityChange.cell_logFoldChange < threshold_low;
            isNoChange = ~isIncrease & ~isDecrease;

            base_image = im2gray(im2single(MatchedCells.GCaMP_Image1));
            se = strel('disk', 15);
            tophat_image = imtophat(base_image, se);
            enhancement_factor = 1.5;
            bright_image = base_image + (tophat_image * enhancement_factor);
            
            imshow(bright_image, 'Parent', target_axes);
            hold(target_axes, 'on');
            
            scatter(target_axes, matchedCentroids(isIncrease,1), matchedCentroids(isIncrease,2), 35, 'o', 'MarkerFaceColor', '#D62728', 'MarkerEdgeColor', '#D62728', 'MarkerFaceAlpha', 0.5, 'MarkerEdgeAlpha', 0.8);
            scatter(target_axes, matchedCentroids(isNoChange,1), matchedCentroids(isNoChange,2), 35, 'o', 'MarkerFaceColor', '#FFFF00', 'MarkerEdgeColor', '#FFFF00', 'MarkerFaceAlpha', 0.4, 'MarkerEdgeAlpha', 0.7);
            scatter(target_axes, matchedCentroids(isDecrease,1), matchedCentroids(isDecrease,2), 35, 'o', 'MarkerFaceColor', '#1F77B4', 'MarkerEdgeColor', '#1F77B4', 'MarkerFaceAlpha', 0.5, 'MarkerEdgeAlpha', 0.8);

            hold(target_axes, 'off');
            
            set(target_axes, 'Visible', 'on', 'XColor', 'none', 'YColor', 'none', 'Color', 'none', 'XTick', [], 'YTick', [], 'Box', 'off');
            
            % --- Set Title: Use provided title or a default ---
            if nargin < 7 || isempty(plotTitle)
                title(target_axes, 'Spatial Distribution of Change', 'Color', 'k', 'BackgroundColor', [1 1 1 0.7], 'FontSize', 10);
            else
                title(target_axes, plotTitle, 'Color', 'k', 'BackgroundColor', [1 1 1 0.7], 'FontSize', 10);
            end

            lgd = legend(target_axes, ['Increase (',num2str(sum(isIncrease)),')'], ['No Change (',num2str(sum(isNoChange)),')'], ['Decrease (',num2str(sum(isDecrease)),')']);
            set(lgd, 'Location', 'northoutside', 'Orientation', 'horizontal', 'Color', 'w', 'TextColor', 'k', 'FontSize', 8, 'Box', 'on');
        end
        
        function plotChangeHistogram(app, target_axes, logFoldChangeData, threshold_high, threshold_low, plotTitle)
            % This version accepts an optional title argument for robust plotting.
            cla(target_axes);
            
            % --- Set Title: Use provided title or a default ---
            if nargin < 6 || isempty(plotTitle)
                title(target_axes, 'Distribution of log2(Fold Change)');
            else
                title(target_axes, plotTitle);
            end

            validData = logFoldChangeData(~isnan(logFoldChangeData));
            if numel(validData) < 2
                text(target_axes, 0.5, 0.5, 'Not enough data for histogram.', 'HorizontalAlignment', 'center');
                return; 
            end
            
            histogram(target_axes, validData, 'BinMethod', 'auto', 'Normalization', 'pdf', 'FaceColor', [0.5 0.5 0.5]);
            hold(target_axes, 'on');
            xlabel(target_axes, 'log2(Intensity2/Intensity1)');
            ylabel(target_axes, 'Density');
            
            if ~isempty(threshold_high) && ~isempty(threshold_low)
                xline(target_axes, threshold_low, '--r', {'Control 2.5%'}, 'LineWidth', 1.5);
                xline(target_axes, threshold_high, '--r', {'Control 97.5%'}, 'LineWidth', 1.5);
            end
            hold(target_axes, 'off');
        end

        function scrollLogToBottom(app)
            % This function scrolls the LogTextArea to the bottom.
            % It uses the modern 'scroll' function, which is the correct
            % method for web-based uifigures in App Designer.
            try
                % Ensure all pending UI updates are processed first
                drawnow; 
                
                % Scroll the text area to the bottom
                scroll(app.LogTextArea, 'bottom');
                
            catch ME
                % This catch block handles cases where the 'scroll' function
                % might not be available (older MATLAB versions) or if another
                % error occurs. It prevents the app from crashing.
                fprintf(2, 'Warning: Auto-scrolling of the log area failed. Error: %s\n', ME.message);
            end
        end

        function saveFigure(app, figHandle, baseFilePath)
            % This robustly saves a figure to three formats:
            % 1. Editable PDF (for Illustrator)
            % 2. Compatible FIG (for MATLAB)
            % 3. High-resolution PNG (for presentations/reports)
            
            % --- The Off-Screen Rendering Trick ---
            try
                screen_size = get(groot, 'ScreenSize');
                set(figHandle, 'Position', [screen_size(3)+50, screen_size(4)+50, 560, 420]);
                set(figHandle, 'Visible', 'on');
                drawnow;
            catch ME
                 app.LogTextArea.Value{end+1} = ['  -> Warning: Could not prepare figure for saving. Error: ' ME.message];
                 return;
            end

            % --- Define File Paths ---
            pdf_path = [baseFilePath '.pdf'];
            fig_path = [baseFilePath '.fig'];
            png_path = [baseFilePath '.png']; % New path for PNG
            
            set(figHandle, 'PaperPositionMode', 'auto');
            
            % --- Save to PNG (NEW) ---
            try
                % Use exportgraphics for high-quality raster output
                exportgraphics(figHandle, png_path, 'Resolution', 300);
                app.LogTextArea.Value{end+1} = ['  -> Saved PNG: ' png_path];
            catch ME
                app.LogTextArea.Value{end+1} = ['  -> Warning: Could not save PNG. Error: ' ME.message];
            end
            
            % --- Save to Editable PDF ---
            try
                print(figHandle, pdf_path, '-dpdf', '-vector', '-bestfit');
                app.LogTextArea.Value{end+1} = ['  -> Saved editable PDF: ' pdf_path];
            catch ME
                app.LogTextArea.Value{end+1} = ['  -> Warning: Could not save PDF. Error: ' ME.message];
            end

            % --- Save to Compatible FIG ---
            try
                savefig(figHandle, fig_path, '-v7.3');
                app.LogTextArea.Value{end+1} = ['  -> Saved compatible .fig: ' fig_path];
            catch ME
                app.LogTextArea.Value{end+1} = ['  -> Warning: Could not save .fig file. Error: ' ME.message];
            end
        end

        function [figHandle, axHandle] = createSaveableFigure(app)
            % Creates a new, invisible figure and axes, ready for plotting.
            figHandle = figure('Visible', 'off', 'NumberTitle', 'off');
            axHandle = axes(figHandle);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectAndLoadButton
        function SelectAndLoadButtonPushed(app, event)
            folderpath1 = uigetdir(pwd, '*** STEP 1: Select the folder for the FIRST timepoint ***');
            if folderpath1 == 0; return; end
            app.FolderPath1 = folderpath1;
            app.Timepoint1EditField.Value = folderpath1; % Corrected name
            [~, name1, ~] = fileparts(folderpath1);
            app.Folder1Name_short = name1;
            drawnow;
            folderpath2 = uigetdir(pwd, '*** STEP 2: Select the folder for the SECOND timepoint ***');
            if folderpath2 == 0; app.LogTextArea.Value = {'Canceled.'}; app.FolderPath1 = []; app.Timepoint1EditField.Value = ''; return; end % Corrected name
            app.FolderPath2 = folderpath2;
            app.Timepoint2EditField.Value = folderpath2; % Corrected name
            [~, name2, ~] = fileparts(folderpath2);
            app.Folder2Name_short = name2;
            drawnow;
            try
                app.FData1 = importdata(fullfile(app.FolderPath1, 'fdata.mat'));
                app.FData2 = importdata(fullfile(app.FolderPath2, 'fdata.mat'));
            catch ME; uialert(app.UIFigure, ['Load Error: ' ME.message], 'Error'); return; end
            if isempty(app.FData1) || isempty(app.FData2); uialert(app.UIFigure, 'Data is empty.', 'Data Error'); return; end
        
            cla(app.Timepoint1Axes); hold(app.Timepoint1Axes, 'on'); title(app.Timepoint1Axes, ['Timepoint 1: ' app.Folder1Name_short]); xlabel(app.Timepoint1Axes, 'Time (s)'); ylabel(app.Timepoint1Axes, 'dF/F (%)');
            x_axis1 = app.FData1(1, :); num_signals1 = size(app.FData1, 1) - 1; num_to_plot1 = min(5, num_signals1);
            if num_to_plot1 > 0; rows_to_plot1 = randperm(num_signals1, num_to_plot1) + 1; plot(app.Timepoint1Axes, x_axis1, app.FData1(rows_to_plot1, :)); end
            hold(app.Timepoint1Axes, 'off');
            cla(app.Timepoint2Axes); hold(app.Timepoint2Axes, 'on'); title(app.Timepoint2Axes, ['Timepoint 2: ' app.Folder2Name_short]); xlabel(app.Timepoint2Axes, 'Time (s)'); ylabel(app.Timepoint2Axes, 'dF/F (%)');
            x_axis2 = app.FData2(1, :); num_signals2 = size(app.FData2, 1) - 1; num_to_plot2 = min(5, num_signals2);
            if num_to_plot2 > 0; rows_to_plot2 = randperm(num_signals2, num_to_plot2) + 1; plot(app.Timepoint2Axes, x_axis2, app.FData2(rows_to_plot2, :)); end
            hold(app.Timepoint2Axes, 'off');
            if num_to_plot1 == 0 || num_to_plot2 == 0; uialert(app.UIFigure, 'No signals in data.', 'Data Error'); return; end
        
            app.CursorLine1 = line(app.Timepoint1Axes, [NaN NaN], get(app.Timepoint1Axes, 'YLim'), 'Color', [0.3 0.3 0.3], 'LineStyle', '--');
            app.CursorLine2 = line(app.Timepoint2Axes, [NaN NaN], get(app.Timepoint2Axes, 'YLim'), 'Color', [0.3 0.3 0.3], 'LineStyle', '--');
            app.SelectionState = 'left';
            app.UIFigure.WindowButtonMotionFcn = @(~,~) app.updateCursorLines();
            app.UIFigure.WindowButtonDownFcn = @(~,~) app.processClick();
            app.LogTextArea.Value = {'Plots loaded. Please click on the LEFT plot to select a frame.'};
            app.InstructionsLabel.Text = 'INSTRUCTIONS: Click on the LEFT plot to select a frame.'; % Corrected name
        end

        % Selection changed function: AnalysisModeGroup
        function AnalysisModeGroupSelectionChanged(app, event)
            selectedButton = app.AnalysisModeGroup.SelectedObject;
            if selectedButton == app.ControlModeButton % Corrected name
                app.ControlAnalysisPanel.Visible = 'on';
                app.ExperimentalAnalysisPanel.Visible = 'off';
            else
                app.ControlAnalysisPanel.Visible = 'off';
                app.ExperimentalAnalysisPanel.Visible = 'on';
            end
        end

        % Button pushed function: SelectControlFoldersButton
        function SelectControlFoldersButtonPushed(app, event)
            app.LogTextArea.Value = {'Starting folder selection...'};
            app.LogTextArea.Value{end+1} = 'Please select your control folders one by one.';
            app.LogTextArea.Value{end+1} = 'Click CANCEL when you are finished.';
            drawnow; 

            all_paths = {};
            last_path = pwd; 
            folder_count = 1; % Initialize the counter

            while true
                % --- START OF MODIFICATION: Add counter to dialog title ---
                dialog_title = sprintf('Select Control Folder #%d (Click Cancel when done)', folder_count);
                folder_path = uigetdir(last_path, dialog_title);
                % --- END OF MODIFICATION ---
                
                if folder_path == 0
                    break; % User clicked Cancel, exit the loop
                end
                
                all_paths{end+1} = folder_path; % Corrected typo: used 'folder_path' instead of 'folderpath'
                last_path = folder_path;
                
                app.LogTextArea.Value{end+1} = sprintf('Added #%d: %s', folder_count, folder_path);
                drawnow;

                folder_count = folder_count + 1; % Increment the counter for the next loop
            end
            
            if ~isempty(all_paths)
                app.LogTextArea.Value{end+1} = '--- Folder selection complete. ---';
                app.ControlFolderPaths = all_paths;
                app.ControlsListBox.Items = all_paths;
                app.LogTextArea.Value{end+1} = [num2str(numel(all_paths)) ' control folders selected. Ready to run analysis.'];
                app.scrollLogToBottom();
            else
                app.LogTextArea.Value{end+1} = 'Folder selection cancelled. No folders were selected.';
                app.scrollLogToBottom();
            end
        end

        % Button pushed function: RunControlButton
        function RunControlButtonPushed(app, event)
           % --- 1. Initial UI Validation & Setup ---
            if isempty(app.ControlFolderPaths), uialert(app.UIFigure, 'Please select control folders first.', 'Error'); return; end
            subfolderName1 = app.Timepoint1SubfolderNameEditField.Value;
            subfolderName2 = app.Timepoint2SubfolderNameEditField.Value;
            if isempty(subfolderName1) || isempty(subfolderName2), uialert(app.UIFigure, 'Please enter subfolder names.', 'Error'); return; end
            if isempty(app.AnalysisSettings), uialert(app.UIFigure, 'Analysis settings are not initialized.', 'Settings Error'); return; end
            
            label1 = app.Timepoint1SubfolderNameEditField.Value; 
            label2 = app.Timepoint2SubfolderNameEditField.Value;
            app.LogTextArea.Value = {'Running Control Analysis...'};
            cla(app.ControlSpatialExampleAxes); title(app.ControlSpatialExampleAxes, ' '); 
            cla(app.ControlsDistributionAxes); title(app.ControlsDistributionAxes, ' ');
            drawnow;
            
            % --- PASS 1: Calculate and save data ---
            app.LogTextArea.Value{end+1} = '--- PASS 1: Calculating and providing live UI updates ---';
            numSamples = numel(app.ControlFolderPaths);
            sampleResults = cell(numSamples, 1);
            
            for i = 1:numSamples
                folder = app.ControlFolderPaths{i};
                pathParts = strsplit(folder, filesep); 
                sampleName = pathParts{end};
                app.LogTextArea.Value{end+1} = ['Processing: ' sampleName]; 
                app.scrollLogToBottom();
                
                currentSampleData = struct('Name', sampleName, 'MatchedCells', [], 'IntensityChange', []);
                
                try
                    outputFolder = app.createOutputFolder(folder); MatchedCells = [];
                    matchedFile = fullfile(outputFolder, 'MatchedCells.mat');
                    if exist(matchedFile, 'file')
                        loaded = load(matchedFile);
                        if isfield(loaded, 'MatchedCells'), MatchedCells = loaded.MatchedCells;
                        else, vars = fieldnames(loaded); if ~isempty(vars), MatchedCells = loaded.(vars{1}); end, end
                    else
                        [MatchedCells, fig_handle] = matchCells(folder, ['\' subfolderName1], ['\' subfolderName2], [], label1, label2);
                        if isgraphics(fig_handle, 'figure'), baseFigPath = fullfile(outputFolder, 'diagnostic_cell_matching'); app.saveFigure(fig_handle, baseFigPath); close(fig_handle); end
                        save(fullfile(outputFolder, 'MatchedCells.mat'), 'MatchedCells');
                    end
                    if isempty(MatchedCells), sampleResults{i} = currentSampleData; continue; end
                    
                    [IntensityChange, radii1_final, radii2_final] = app.calculateIntensityChange(MatchedCells);
                    save(fullfile(outputFolder, 'IntensityChange.mat'), 'IntensityChange');
                    
                    if isempty(fieldnames(IntensityChange)), sampleResults{i} = currentSampleData; continue; end
                    
                    currentSampleData.MatchedCells = MatchedCells;
                    currentSampleData.IntensityChange = IntensityChange;
                    
                    if app.AnalysisSettings.RecalculateRadii
                        % --- CORRECTED FIGURE CREATION LOGIC ---
                        diagFig = figure('Name', ['Radii Diagnostic: ' sampleName], 'NumberTitle', 'off', 'Visible', 'off');
                        t = tiledlayout(diagFig, 1, 2, 'Padding', 'compact', 'TileSpacing', 'compact');
                        title(t, ['Original (Red) vs. Recalculated (Green) Radii for ' sampleName]);
                        ax1 = nexttile(t); imshow(MatchedCells.GCaMP_Image1, 'Parent', ax1); hold(ax1, 'on');
                        viscircles(ax1, MatchedCells.matchedCentroidCoordinates_0minus, MatchedCells.matchedRadii_0minus, 'Color', 'r');
                        viscircles(ax1, MatchedCells.matchedCentroidCoordinates_0minus, radii1_final, 'Color', 'g');
                        title(ax1, label1); hold(ax1, 'off');
                        ax2 = nexttile(t); imshow(MatchedCells.GCaMP_Image2, 'Parent', ax2); hold(ax2, 'on');
                        viscircles(ax2, MatchedCells.matchedCentroidCoordinates_timepoint2, MatchedCells.matchedRadii_timepoint2, 'Color', 'r');
                        viscircles(ax2, MatchedCells.matchedCentroidCoordinates_timepoint2, radii2_final, 'Color', 'g');
                        title(ax2, label2); hold(ax2, 'off');
                        baseFigPath = fullfile(outputFolder, 'diagnostic_recalculated_radii');
                        app.saveFigure(diagFig, baseFigPath);
                        close(diagFig);
                    end
                    
                    sampleResults{i} = currentSampleData;

                catch ME
                    app.LogTextArea.Value{end+1} = ['!!! ERROR processing ' sampleName ': ' getReport(ME, 'basic')];
                    sampleResults{i} = currentSampleData;
                    continue;
                end
            end
            
            % --- PASS 2: Aggregate data ---
            all_log2FC = [];
            valid_sample_indices = find(~cellfun(@(x) isempty(x) || isempty(x.IntensityChange), sampleResults));
            for idx = valid_sample_indices'
                res = sampleResults{idx};
                all_log2FC = [all_log2FC; res.IntensityChange.cell_logFoldChange(~isnan(res.IntensityChange.cell_logFoldChange))];
            end
            if isempty(all_log2FC), uialert(app.UIFigure, 'No valid cell data found across all control samples.', 'Fatal Analysis Error'); return; end
            
            alpha = 2.5;
            global_threshold_low = prctile(all_log2FC, alpha);
            global_threshold_high = prctile(all_log2FC, 100 - alpha);
            
            % --- PASS 3: Generate and SAVE per-sample plots ---
            app.LogTextArea.Value{end+1} = '--- PASS 2: Generating and saving per-sample plots ---';
            for i = 1:numel(valid_sample_indices)
                idx = valid_sample_indices(i);
                res = sampleResults{idx};
                outputFolder = app.createOutputFolder(app.ControlFolderPaths{idx});
                
                [histFig, ax_hist] = app.createSaveableFigure();
                app.plotChangeHistogram(ax_hist, res.IntensityChange.cell_logFoldChange, global_threshold_high, global_threshold_low, ['Histogram for ' res.Name]);
                baseFigPath = fullfile(outputFolder, [res.Name '_histogram']);
                app.saveFigure(histFig, baseFigPath);
                close(histFig);
                
                [spatialFig, ax_spatial] = app.createSaveableFigure();
                app.plotSpatialDistribution(ax_spatial, res.MatchedCells, res.IntensityChange, global_threshold_high, global_threshold_low, ['Spatial Plot for ' res.Name]);
                baseFigPath = fullfile(outputFolder, [res.Name '_spatial_distribution']);
                app.saveFigure(spatialFig, baseFigPath);
                close(spatialFig);
            end

            % --- PASS 4: Update UI and log summary ---
            app.LogTextArea.Value{end+1} = '--- Updating UI with final global results ---';
            
            % --- CORRECTED UI UPDATE LOGIC ---
            app.plotChangeHistogram(app.ControlsDistributionAxes, all_log2FC, global_threshold_high, global_threshold_low, 'Global Distribution (All Controls)');
            if ~isempty(valid_sample_indices)
                res = sampleResults{valid_sample_indices(1)};
                app.plotSpatialDistribution(app.ControlSpatialExampleAxes, res.MatchedCells, res.IntensityChange, global_threshold_high, global_threshold_low, ['Spatial Example: ' res.Name]);
            end
            
            [file, path] = uiputfile('Control_Thresholds.mat', 'Save Combined Control Thresholds File');
            if file ~= 0
                threshold_high = global_threshold_high;
                threshold_low = global_threshold_low;
                save(fullfile(path, file), 'threshold_high', 'threshold_low', 'all_log2FC');
            end
            
            app.LogTextArea.Value{end+1} = '---'; 
            app.LogTextArea.Value{end+1} = '--- ANALYSIS COMPLETE ---';
            app.LogTextArea.Value{end+1} = sprintf('Global Thresholds Calculated (%d cells from %d samples): Low=%.4f, High=%.4f', numel(all_log2FC), numel(valid_sample_indices), global_threshold_low, global_threshold_high);
            summaryReport = {'--- Per-Sample Results (using GLOBAL thresholds) ---'};
            for i = 1:numSamples
                res = sampleResults{i};
                if isempty(res) || isempty(res.IntensityChange), summaryReport{end+1,1} = sprintf('  - %s: Skipped or failed.', res.Name); continue; end
                isIncrease = res.IntensityChange.cell_logFoldChange > global_threshold_high;
                isDecrease = res.IntensityChange.cell_logFoldChange < global_threshold_low;
                isNoChange = ~isIncrease & ~isDecrease;
                summaryReport{end+1,1} = sprintf('  - %s: Mean log2(FC)=%.3f, Inc/NoCh/Dec=[%d/%d/%d]', res.Name, res.IntensityChange.mean_logFoldChange, sum(isIncrease,'omitnan'), sum(isNoChange,'omitnan'), sum(isDecrease,'omitnan'));
            end
            app.LogTextArea.Value = [app.LogTextArea.Value; summaryReport];
            if file ~= 0, app.LogTextArea.Value{end+1} = ['Control thresholds saved to: ' fullfile(path, file)]; end
            app.scrollLogToBottom();
        end

        % Button pushed function: SelectExperimentalFolderButton
        function SelectExperimentalFolderButtonPushed(app, event)
            folderpath = uigetdir(pwd, '*Select folder of the experimental sample*');
            if folderpath == 0; return; end
            app.ExperimentalFolderPath = folderpath;
            app.ExperimentalFolderField.Value = folderpath;
        end

        % Button pushed function: LoadControlThresholdsButton
        function LoadControlThresholdsButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Select Control Thresholds File');
    
            % Proceed only if the user did not press "Cancel"
            if file ~= 0
                filepath = fullfile(path, file);
                try
                    data = load(filepath, 'threshold_high', 'threshold_low');
                    if isfield(data, 'threshold_high') && isfield(data, 'threshold_low')
                        app.ThresholdData = data;
                        app.ControlThresholdsField.Value = filepath; % Update the UI to show the path
                        app.LogTextArea.Value = {'Thresholds loaded successfully.'};
                    else
                        uialert(app.UIFigure, 'The selected .mat file does not contain the required threshold variables.', 'File Error');
                    end
                catch ME
                    uialert(app.UIFigure, ['Failed to load file. Error: ' ME.message], 'Load Error');
                end
            end
        end

        % Button pushed function: RunExperimentalButton
        function RunExperimentalButtonPushed(app, event)
            % --- 1. Initial UI Validation & Setup ---
            if isempty(app.ExperimentalFolderPath), uialert(app.UIFigure, 'Please select an experimental folder.', 'Error'); return; end
            subfolderName1 = app.ExpTimepoint1SubfolderNameEditField.Value;
            subfolderName2 = app.ExpTimepoint2SubfolderNameEditField.Value;
            if isempty(subfolderName1) || isempty(subfolderName2), uialert(app.UIFigure, 'Please enter subfolder names.', 'Error'); return; end
            if isempty(app.AnalysisSettings), uialert(app.UIFigure, 'Analysis settings are not initialized.', 'Settings Error'); return; end

            app.LogTextArea.Value = {'Running Experimental Analysis...'};
            app.LogTextArea.Value{end+1} = sprintf('Using settings: Method=%s, Inner=%.2f, Outer=%.2f, RecalcRadii=%d', ...
                app.AnalysisSettings.Method, app.AnalysisSettings.InnerFactor, app.AnalysisSettings.OuterFactor, app.AnalysisSettings.RecalculateRadii);
            app.scrollLogToBottom();
            
            folder = app.ExperimentalFolderPath;
            pathParts = strsplit(folder, filesep);
            sampleName = pathParts{end};

            try
                % --- Load or create matched cells data ---
                outputFolder = app.createOutputFolder(folder);
                MatchedCells = [];
                matchedFile = fullfile(outputFolder, 'MatchedCells.mat');
                
                if exist(matchedFile, 'file')
                    app.LogTextArea.Value{end+1} = 'Loading existing MatchedCells.mat...';
                    loaded = load(matchedFile);
                    if isfield(loaded, 'MatchedCells'), MatchedCells = loaded.MatchedCells;
                    else, vars = fieldnames(loaded); if ~isempty(vars), MatchedCells = loaded.(vars{1}); end; end
                else
                    app.LogTextArea.Value{end+1} = 'No MatchedCells.mat found. Please follow prompts for manual cell matching...';
                    drawnow;
                    
                    image1_subfolder = fullfile(filesep, subfolderName1);
                    image2_subfolder = fullfile(filesep, subfolderName2);
                    
                    % use the experimental subfolder names as labels
                    label1 = subfolderName1;
                    label2 = subfolderName2;
                    [MatchedCells, fig_handle_matching] = matchCells(folder, image1_subfolder, image2_subfolder, [], label1, label2);
                    
                    if ishandle(fig_handle_matching)
                        baseFigPath = fullfile(outputFolder, 'diagnostic_cell_matching');
                        app.saveFigure(fig_handle_matching, baseFigPath);
                        close(fig_handle_matching);
                    end
                    
                    app.LogTextArea.Value{end+1} = 'Saving new MatchedCells.mat...';
                    save(matchedFile, 'MatchedCells');
                end
                
                if isempty(MatchedCells), uialert(app.UIFigure, 'Failed to load or create matched cell data.', 'Data Error'); return; end
                
                % --- Perform the main calculation ---
                [IntensityChange, radii1_final, radii2_final] = app.calculateIntensityChange(MatchedCells);

                if ~isempty(fieldnames(IntensityChange))
                    plotTitle = ['Live View: ' sampleName ' Histogram'];
                    app.plotChangeHistogram(app.ExperimentalDistributionAxes, IntensityChange.cell_logFoldChange, [], [], plotTitle);
                    cla(app.ControlSpatialExampleAxes); 
                    title(app.ControlSpatialExampleAxes, 'Awaiting Global Thresholds...');
                    drawnow;
                end
                
                % --- Determine Thresholds and Plot Titles ---
                threshold_source_msg = '';
                if ~isempty(app.ThresholdData) % Control thresholds are available
                    th_high = app.ThresholdData.threshold_high;
                    th_low = app.ThresholdData.threshold_low;
                    threshold_source_msg = 'Using loaded control thresholds for stats.';
                    plot_title_hist = 'Experimental Baseline Change Distribution (using Control Thresholds)';
                else % No control thresholds loaded
                    clean_data = IntensityChange.cell_logFoldChange(isfinite(IntensityChange.cell_logFoldChange) & isreal(IntensityChange.cell_logFoldChange));
                    if numel(clean_data) >= 2
                        th_high = prctile(clean_data, 97.5);
                        th_low = prctile(clean_data, 2.5);
                        threshold_source_msg = 'Using this sample''s 2.5/97.5 percentiles for stats.';
                        plot_title_hist = 'Experimental Baseline Change Distribution (Sample Thresholds)';
                    else
                        th_high = NaN; % Cannot determine thresholds
                        th_low = NaN;
                        threshold_source_msg = 'Not enough valid data for sample-specific thresholds.';
                        plot_title_hist = 'Experimental Baseline Change Distribution (Not Enough Data)';
                    end
                end

                % --- FINAL PLOTTING AND SAVING ---
                app.LogTextArea.Value{end+1} = '--- Saving Plots... ---';
                
                % Plot to UI with correct titles
                app.plotChangeHistogram(app.ExperimentalDistributionAxes, IntensityChange.cell_logFoldChange, th_high, th_low, plot_title_hist);
                app.plotSpatialDistribution(app.ExperimentalSpatialAxes, MatchedCells, IntensityChange, th_high, th_low, ['Spatial Distribution for ' sampleName]);
                
                % Save histogram plot
                histFig = figure('Visible', 'off');
                ax_hist = axes(histFig); 
                app.plotChangeHistogram(ax_hist, IntensityChange.cell_logFoldChange, th_high, th_low); % Use determined thresholds
                baseFigPath = fullfile(outputFolder, 'baselineChange_histogram');
                app.saveFigure(histFig, baseFigPath);
                close(histFig);
                
                % --- Save spatial plot ONLY if thresholds and data are valid ---
                if ~isnan(th_high) && ~isnan(th_low) && ~isempty(IntensityChange.cell_logFoldChange)
                    spatialFig = figure('Visible', 'off');
                    ax_spatial = axes(spatialFig); 
                    app.plotSpatialDistribution(ax_spatial, MatchedCells, IntensityChange, th_high, th_low); % Use determined thresholds
                    baseFigPath = fullfile(outputFolder, 'baselineChange_spatialDistribution');
                    app.saveFigure(spatialFig, baseFigPath);
                    close(spatialFig);
                else
                    app.LogTextArea.Value{end+1} = 'Skipping spatial distribution plot due to lack of valid thresholds or data.';
                end
                
                % Save excluded cells plot (this one doesn't inherently use thresholds)
                diagFig_exp = figure('Visible', 'off');
                app.createExcludedCellsFigure(diagFig_exp, ...
                    IntensityChange.image1_for_plotting, IntensityChange.centroids1_for_plotting, IntensityChange.invalidMask_for_plotting);
                baseFigPath = fullfile(outputFolder, 'excluded_cells');
                app.saveFigure(diagFig_exp, baseFigPath);
                close(diagFig_exp);
                
                % --- Save IntensityChange.mat ---
                save(fullfile(outputFolder, 'IntensityChange.mat'), 'IntensityChange');
                app.scrollLogToBottom();

                % --- FINAL SUMMARY LOGGING ---
                app.LogTextArea.Value{end+1} = '---';
                app.LogTextArea.Value{end+1} = '--- ANALYSIS COMPLETE ---';
                app.LogTextArea.Value{end+1} = ['Sample: ' sampleName];
                app.LogTextArea.Value{end+1} = ['    - ' threshold_source_msg];
                app.LogTextArea.Value{end+1} = sprintf('    - Mean log2(FC): %.4f', IntensityChange.mean_logFoldChange);
                
                % Recalculate counts for summary based on determined thresholds
                isIncrease = IntensityChange.cell_logFoldChange > th_high;
                isDecrease = IntensityChange.cell_logFoldChange < th_low;
                isNoChange = ~isIncrease & ~isDecrease;
                
                app.LogTextArea.Value{end+1} = sprintf('    - Increase / No Change / Decrease: %d / %d / %d', ...
                    sum(isIncrease,'omitnan'), sum(isNoChange,'omitnan'), sum(isDecrease,'omitnan'));
                app.scrollLogToBottom();
                
            catch ME
                uialert(app.UIFigure, ['Analysis Error: ' ME.message], 'Error');
                app.LogTextArea.Value{end+1} = getReport(ME, 'extended', 'hyperlinks', 'off');
                app.scrollLogToBottom();
            end

            uialert(app.UIFigure, 'Analysis Completed Successfully!', 'Success', 'Icon', 'success');
        end

        % Button pushed function: FindOptimalSettingsButton
        function FindOptimalSettingsButtonPushed(app, event)
            % --- STAGE 0: UI Validation ---
            if isempty(app.FolderPath1) || isempty(app.FolderPath2)
                uialert(app.UIFigure, 'Please complete Tab 1 first.', 'No Data Loaded');
                return;
            end
            app.LogTextArea.Value = {'--- Starting Optimization ---'};
            
            % --- STAGE 1: LOAD Matched Cell Data ---
            app.LogTextArea.Value{end+1} = 'Step 1: Looking for existing MatchedCells.mat...';
            drawnow;
        
            parentFolder = fileparts(app.FolderPath1);
            outputFolder = fullfile(parentFolder, 'BaselineChange');
            if ~exist(outputFolder, 'dir'), mkdir(outputFolder); end
            matchedFile = fullfile(outputFolder, 'MatchedCells.mat'); 
        
            if ~exist(matchedFile, 'file')
                errorMsg = sprintf(['Optimization requires a "MatchedCells.mat" file.\n\n' ...
                                  'Please run the analysis in the "Experimental" tab for this sample first ' ...
                                  'to perform the manual point selection and create the file.']);
                uialert(app.UIFigure, errorMsg, 'File Not Found');
                app.LogTextArea.Value{end+1} = 'Error: MatchedCells.mat not found. Please run experimental analysis first.';
                return;
            end
            
            try
                app.LogTextArea.Value{end+1} = '    Loading MatchedCells.mat...';
                
                % First, try to load the specific variable 'MatchedCells'
                loadedData = load(matchedFile, 'MatchedCells');
    
                % Now, check if that worked.
                if isfield(loadedData, 'MatchedCells')
                    % The standard case: the file contains the 'MatchedCells' variable.
                    app.TempMatchedData = loadedData.MatchedCells;
                    app.LogTextArea.Value{end+1} = '    -> Found and loaded "MatchedCells" variable.';
                else
                    % The fallback case: 'MatchedCells' wasn't found.
                    app.LogTextArea.Value{end+1} = '    -> "MatchedCells" variable not found. Loading first variable from file as a fallback.';
                    loaded_all = load(matchedFile);
                    vars = fieldnames(loaded_all);
                    
                    if ~isempty(vars)
                        % Assign the data from the first variable found in the file.
                        app.TempMatchedData = loaded_all.(vars{1});
                        app.LogTextArea.Value{end+1} = ['    -> Loaded data from variable named "' vars{1} '".'];
                    else
                        % This is a more serious error - the file is empty.
                        uialert(app.UIFigure, 'The MatchedCells.mat file is empty or corrupted.', 'File Load Error');
                        app.LogTextArea.Value{end+1} = '    -> ERROR: The .mat file is empty.';
                        return;
                    end
                end
            catch ME
                uialert(app.UIFigure, ['Could not read existing MatchedCells.mat file. Error: ' ME.message], 'File Load Error');
                return;
            end

            app.LogTextArea.Value{end+1} = sprintf('    Using data for %d matched cells.', size(app.TempMatchedData.matchedCentroidCoordinates_0minus, 1));
        
            % --- STAGE 2: Load images for optimization ---
            app.LogTextArea.Value{end+1} = 'Step 2: Loading images from matched data structure...';
            drawnow;
        
            try
                image1_raw = app.TempMatchedData.GCaMP_Image1;
                image2_raw = app.TempMatchedData.GCaMP_Image2;
                centroids1 = app.TempMatchedData.matchedCentroidCoordinates_0minus;
                radii1 = app.TempMatchedData.matchedRadii_0minus;
                centroids2 = app.TempMatchedData.matchedCentroidCoordinates_timepoint2;
                radii2 = app.TempMatchedData.matchedRadii_timepoint2;
        
                se = strel('disk', 15);
                image1_processed = imtophat(im2single(image1_raw), se);
                image2_processed = imtophat(im2single(image2_raw), se);
            catch ME
                uialert(app.UIFigure, ['Could not load data from matched struct. Error: ' ME.message], 'Load Error');
                return;
            end
        
            % --- STAGE 3: EFFICIENTLY RECALCULATE RADII (IF CHECKED) ---
            radii1_for_optim = radii1; 
            radii2_for_optim = radii2;
            
            if app.RecalculateRadiiCheckBox.Value
                app.LogTextArea.Value{end+1} = 'Step 3: Recalculating all cell radii (this may take time)...';
                d_radii = uiprogressdlg(app.UIFigure, 'Title', 'Recalculating Radii', 'Message', 'Processing...', 'Cancelable', 'on', 'Indeterminate', 'on');
                drawnow;
                
                numCells = size(centroids1, 1);
                recalculated_radii1 = zeros(numCells, 1);
                recalculated_radii2 = zeros(numCells, 1);
                
                try
                    parfor c = 1:numCells
                        r1_orig_cell = radii1(c);
                        patch_half_width1 = ceil(r1_orig_cell * 2.5);
                        ymin1 = max(1, round(centroids1(c,2)) - patch_half_width1);
                        ymax1 = min(size(image1_processed,1), round(centroids1(c,2)) + patch_half_width1);
                        xmin1 = max(1, round(centroids1(c,1)) - patch_half_width1);
                        xmax1 = min(size(image1_processed,2), round(centroids1(c,1)) + patch_half_width1);
                        patch1 = image1_processed(ymin1:ymax1, xmin1:xmax1);
                        if ~isempty(patch1)
                            recalculated_radii1(c) = findBaselineRadius(patch1, r1_orig_cell);
                        else
                            recalculated_radii1(c) = r1_orig_cell;
                        end
        
                        r2_orig_cell = radii2(c);
                        patch_half_width2 = ceil(r2_orig_cell * 2.5);
                        ymin2 = max(1, round(centroids2(c,2)) - patch_half_width2);
                        ymax2 = min(size(image2_processed,1), round(centroids2(c,2)) + patch_half_width2);
                        xmin2 = max(1, round(centroids2(c,1)) - patch_half_width2);
                        xmax2 = min(size(image2_processed,2), round(centroids2(c,1)) + patch_half_width2);
                        patch2 = image2_processed(ymin2:ymax2, xmin2:xmax2);
                        if ~isempty(patch2)
                            recalculated_radii2(c) = findBaselineRadius(patch2, r2_orig_cell);
                        else
                            recalculated_radii2(c) = r2_orig_cell;
                        end
                    end
                    
                    if d_radii.CancelRequested, close(d_radii); app.LogTextArea.Value{end+1} = 'Radius recalculation canceled.'; return; end
                    
                    app.LogTextArea.Value{end+1} = '    -> Generating diagnostic radii plot...';
                    drawnow;
                    
                    diagFig = figure('Name', 'Radii Recalculation Diagnostic', 'NumberTitle', 'off', 'Visible', 'off');
                    t = tiledlayout(1, 2, 'Padding', 'compact', 'TileSpacing', 'compact');
                    title(t, 'Original (Red) vs. Recalculated (Green) Radii');
        
                    ax1 = nexttile;
                    imshow(image1_raw, 'Parent', ax1);
                    hold(ax1, 'on');
                    viscircles(ax1, centroids1, radii1, 'Color', 'r', 'LineWidth', 0.5, 'LineStyle', '--');
                    viscircles(ax1, centroids1, recalculated_radii1, 'Color', 'g', 'LineWidth', 1);
                    title(ax1, 'Timepoint 1');
                    hold(ax1, 'off');
        
                    ax2 = nexttile;
                    imshow(image2_raw, 'Parent', ax2);
                    hold(ax2, 'on');
                    viscircles(ax2, centroids2, radii2, 'Color', 'r', 'LineWidth', 0.5, 'LineStyle', '--');
                    viscircles(ax2, centroids2, recalculated_radii2, 'Color', 'g', 'LineWidth', 1);
                    title(ax2, 'Timepoint 2');
                    hold(ax2, 'off');
                    
                    baseFigPath = fullfile(outputFolder, 'diagnostic_recalculated_radii_OPTIMIZATION');
                    app.saveFigure(diagFig, baseFigPath);
                    close(diagFig);
                    app.LogTextArea.Value{end+1} = ['    -> Diagnostic plots saved to folder: ' outputFolder];

                    radii1_for_optim = recalculated_radii1;
                    radii2_for_optim = recalculated_radii2;
                    app.LogTextArea.Value{end+1} = '    -> Radius recalculation complete.';
                    
                catch ME
                    close(d_radii);
                    uialert(app.UIFigure, ['Error during radius recalculation: ' ME.message], 'Error');
                    return;
                end
                close(d_radii);
            else
                app.LogTextArea.Value{end+1} = 'Step 3: Skipping radius recalculation.';
            end
        
            % --- STAGE 4: RUN MAIN OPTIMIZATION ---
            app.FindOptimalSettingsButton.Enable = 'off';
            app.ApplyandSaveButton.Enable = 'off';
            app.VisualizeFailedCellsButton.Enable = 'off';
            app.LogTextArea.Value{end+1} = 'Step 4: Optimizing background settings...';
            
            d_optim = uiprogressdlg(app.UIFigure, 'Title', 'Optimizing Background', 'Message', 'Running MEDIAN tests...', 'Indeterminate', 'on', 'Cancelable', 'on');
            
            try
                median_results = app.optimizeBackgroundSettings(...
                    image1_processed, image2_processed, ...
                    centroids1, radii1_for_optim, ...
                    centroids2, radii2_for_optim, ...
                    'Median');
                
                if d_optim.CancelRequested, close(d_optim); app.FindOptimalSettingsButton.Enable = 'on'; return; end
                d_optim.Message = 'Running MEAN tests...';
                
                mean_results = app.optimizeBackgroundSettings(...
                    image1_processed, image2_processed, ...
                    centroids1, radii1_for_optim, ...
                    centroids2, radii2_for_optim, ...
                    'Mean');
                    
                if d_optim.CancelRequested, close(d_optim); app.FindOptimalSettingsButton.Enable = 'on'; return; end
            
            catch ME
                close(d_optim);
                app.FindOptimalSettingsButton.Enable = 'on';
                uialert(app.UIFigure, ['Optimization Error: ' ME.message], 'Error');
                return;
            end
            close(d_optim);
        
            % --- STAGE 5: PROCESS AND DISPLAY RESULTS ---
            if ~isempty(median_results) && ~isempty(mean_results)
                full_results = sortrows([median_results; mean_results], 'QualityScore_percent', 'descend');
                app.OptimizationResults = full_results;
                app.SettingsResultsTable.Data = full_results;
                app.SettingsResultsTable.ColumnName = {'Inner Factor', 'Outer Factor', 'Method', 'Quality Score (%)'};
                addStyle(app.SettingsResultsTable, uistyle('BackgroundColor', '#90EE90'), 'row', 1);
                winner = app.OptimizationResults(1,:);
                app.LogTextArea.Value{end+1} = sprintf('Optimization Complete. Best method: %s (%.2f%% score)', winner.Method{:}, winner.QualityScore_percent);
                app.ApplyandSaveButton.Enable = 'on';
                app.VisualizeFailedCellsButton.Enable = 'on';
            else
                app.LogTextArea.Value{end+1} = 'Optimization did not return results.';
            end
            app.FindOptimalSettingsButton.Enable = 'on';
        end

        % Button pushed function: ApplyandSaveButton
        function ApplyandSaveButtonPushed(app, event)
            if isempty(app.OptimizationResults)
                uialert(app.UIFigure, 'No optimization results to apply.', 'Error');
                return;
            end
            bestSettings = app.OptimizationResults(1, :);
            
            % Check if the AnalysisSettings struct has not been initialized yet.
            if isempty(app.AnalysisSettings)
                % If it's empty, initialize it as a scalar struct before adding fields.
                app.AnalysisSettings = struct();
                app.LogTextArea.Value{end+1} = 'Note: Initializing settings struct before applying optimal settings.';
            end
        
            % Now we are GUARANTEED that app.AnalysisSettings is a valid struct,
            % so dot notation assignment will work.
            
            % Update the app's internal settings property
            app.AnalysisSettings.Method = bestSettings.Method{:};
            app.AnalysisSettings.InnerFactor = bestSettings.InnerFactor;
            app.AnalysisSettings.OuterFactor = bestSettings.OuterFactor;
            app.AnalysisSettings.RecalculateRadii = app.RecalculateRadiiCheckBox.Value;
            
            % Also update the manual UI controls to reflect the new optimal settings
            app.ManualMethodDropDown.Value = app.AnalysisSettings.Method;
            app.ManualInnerEditField.Value = app.AnalysisSettings.InnerFactor;
            app.ManualOuterEditField.Value = app.AnalysisSettings.OuterFactor;
            
            % Save the new optimal settings
            appPath = app.getAppPath();
            settingsFile = fullfile(appPath, 'CalciumBaselineAnalysisSettings.mat');
            savedSettings = app.AnalysisSettings; % The variable to save is the whole struct
            try
                save(settingsFile, 'savedSettings'); % Save the variable named 'savedSettings'
                uialert(app.UIFigure, sprintf('Optimal settings (Method: %s, Inner: %.2f, Outer: %.2f) have been applied and saved.', ...
                    app.AnalysisSettings.Method, app.AnalysisSettings.InnerFactor, app.AnalysisSettings.OuterFactor), 'Settings Applied','Icon','success');
            catch ME
                uialert(app.UIFigure, ['Save Error: ' ME.message], 'Save Error');
            end
        end

        % Button pushed function: VisualizeFailedCellsButton
        function VisualizeFailedCellsButtonPushed(app, event)
            % Check that an optimization has been run and we have results
            if isempty(app.OptimizationResults)
                uialert(app.UIFigure, 'Please run the optimization first.', 'No Results');
                return;
            end
            
            app.LogTextArea.Value{end+1} = 'Generating diagnostic plot for failed cells...';
            drawnow;
            
            try
                % --- Check if the temporary matched data exists ---
                if isempty(app.TempMatchedData)
                    uialert(app.UIFigure, 'No matched data found. Please run the optimization first.', 'Error');
                    return;
                end
            
                % --- Use the data from the TempMatchedData struct ---
                image1_raw = app.TempMatchedData.GCaMP_Image1;
                centroids1 = app.TempMatchedData.matchedCentroidCoordinates_0minus;
                radii1 = app.TempMatchedData.matchedRadii_0minus;
                
                % --- Get the BEST settings from the previous optimization ---
                bestSettings = app.OptimizationResults(1, :);
                bgMethod = bestSettings.Method{:};
                bgInnerFactor = bestSettings.InnerFactor;
                bgOuterFactor = bestSettings.OuterFactor;
                
                % --- Apply Top-Hat filtering (same as in the optimization) ---
                se = strel('disk', 15);
                image1 = imtophat(image1_raw, se);
                
                % --- Re-calculate intensities for all cells using ONLY the best settings ---
                numCells = size(centroids1, 1);
                intensities = zeros(numCells, 1);
                
                for c = 1:numCells
                    % Call the single, authoritative function
                    [intensities(c), ~] = calculateSingleCellIntensity(...
                        image1, ...
                        centroids1(c,1), centroids1(c,2), radii1(c), ...
                        bgInnerFactor, ...
                        bgOuterFactor, ...
                        bgMethod);
                end
                
                % --- Identify good and bad cells ---
                isGood = intensities > 0;
                isBad = ~isGood;
                
                % --- Create a NEW figure for the diagnostic plot ---
                diagFig = figure('Name', 'Diagnostic Plot: ROI Quality', 'NumberTitle', 'off');
                imshow(image1_raw); % Show the original image for clarity
                hold on;
                
                % Plot good cells in green
                viscircles(centroids1(isGood, :), radii1(isGood), 'Color', 'g', 'LineWidth', 0.5);
                
                % Plot bad cells in red
                viscircles(centroids1(isBad, :), radii1(isBad), 'Color', 'r', 'LineWidth', 1);
                
                title(sprintf('ROI Quality Check: %d Good Cells (Green), %d Failed Cells (Red)', sum(isGood), sum(isBad)));
                hold off;
                
                outputFolder = app.createOutputFolder(fileparts(app.FolderPath1));
                baseFigPath = fullfile(outputFolder, 'diagnostic_ROI_quality');

                app.saveFigure(diagFig, baseFigPath);
                % We don't close the figure here, so the user can visualize it.

                app.LogTextArea.Value{end+1} = 'Diagnostic plot created and saved.';
                
            catch ME
                uialert(app.UIFigure, ['Could not generate diagnostic plot. Error: ' ME.message], 'Diagnostic Error');
                disp(ME.getReport);
            end
        end

        % Button pushed function: ApplyManualButton
        function ApplyManualButtonPushed(app, event)
            % Check if the AnalysisSettings struct has not been initialized yet.
            if isempty(app.AnalysisSettings)
                % If it's empty, initialize it as a scalar struct before trying to add fields.
                app.AnalysisSettings = struct(); 
                app.LogTextArea.Value{end+1} = 'Note: Initializing settings struct.';
            end
            
            % Now, we are GUARANTEED that app.AnalysisSettings is a valid struct,
            % so dot notation assignment will work.
            
            % Read values from the manual input UI controls
            app.AnalysisSettings.Method = app.ManualMethodDropDown.Value;
            app.AnalysisSettings.InnerFactor = app.ManualInnerEditField.Value;
            app.AnalysisSettings.OuterFactor = app.ManualOuterEditField.Value;
            app.AnalysisSettings.RecalculateRadii = app.RecalculateRadiiCheckBox.Value;
            
            % Save these manually entered settings to the file
            appPath = app.getAppPath();
            settingsFile = fullfile(appPath, 'CalciumBaselineAnalysisSettings.mat');
            savedSettings = app.AnalysisSettings;
            try
                save(settingsFile, 'savedSettings');
                uialert(app.UIFigure, sprintf('Manual settings (Method: %s, Inner: %.2f, Outer: %.2f) have been applied and saved.', ...
                    app.AnalysisSettings.Method, app.AnalysisSettings.InnerFactor, app.AnalysisSettings.OuterFactor), 'Settings Saved','Icon','success');
                app.LogTextArea.Value{end+1} = 'Manual settings saved.';
            catch ME
                uialert(app.UIFigure, ['Could not save settings. Error: ' ME.message], 'Save Error');
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % Check if settings are missing (e.g., if app startup somehow failed)
            if isempty(app.AnalysisSettings)
                app.LogTextArea.Value{end+1} = 'Settings were not loaded. Attempting to load now...';
                
                % Step 1: Initialize with a complete default struct as a fallback
                app.AnalysisSettings = struct(...
                    'Method', 'Median', ...
                    'InnerFactor', 1.8, ...
                    'OuterFactor', 2.0, ...
                    'RecalculateRadii', false); % Ensure default is complete

                % Step 2: Attempt to load from the saved file
                settingsFile = fullfile(app.getAppPath(), 'CalciumBaselineAnalysisSettings.mat');
                if exist(settingsFile, 'file')
                    try
                        loadedVars = load(settingsFile, 'savedSettings');
                        if isfield(loadedVars, 'savedSettings') && isstruct(loadedVars.savedSettings)
                            sourceStruct = loadedVars.savedSettings;
                            
                            % --- THE FIX IS HERE: Validate and apply ALL fields ---
                            if isfield(sourceStruct, 'Method')
                                app.AnalysisSettings.Method = sourceStruct.Method;
                            end
                            if isfield(sourceStruct, 'InnerFactor')
                                app.AnalysisSettings.InnerFactor = sourceStruct.InnerFactor;
                            end
                            if isfield(sourceStruct, 'OuterFactor')
                                app.AnalysisSettings.OuterFactor = sourceStruct.OuterFactor;
                            end
                            if isfield(sourceStruct, 'RecalculateRadii')
                                app.AnalysisSettings.RecalculateRadii = sourceStruct.RecalculateRadii;
                            end
                            % --- END OF FIX ---
                            
                            app.LogTextArea.Value{end+1} = 'Successfully re-loaded settings from file.';
                        else
                           app.LogTextArea.Value{end+1} = 'Warning: Settings file found but is malformed. Using defaults.';
                        end
                    catch ME
                        app.LogTextArea.Value{end+1} = ['Error loading settings file: ' ME.message '. Using defaults.'];
                    end
                else
                    app.LogTextArea.Value{end+1} = 'No settings file found. Using defaults.';
                end
                 app.LogTextArea.Value{end+1} = sprintf(...
                    'Current settings: Method=%s, Inner=%.2f, Outer=%.2f, RecalcRadii=%d', ...
                    app.AnalysisSettings.Method, ...
                    app.AnalysisSettings.InnerFactor, ...
                    app.AnalysisSettings.OuterFactor, ...
                    app.AnalysisSettings.RecalculateRadii);
            end

            % --- Original Tab Switching UI Logic ---
            selectedTab = app.TabGroup.SelectedTab;
    
            if selectedTab == app.AnalysisPipelineTab
                callbackToExecute = app.AnalysisModeGroup.SelectionChangedFcn;
                event_data.Source = app.AnalysisModeGroup;
                callbackToExecute(app.AnalysisModeGroup, event_data);
            
            elseif selectedTab == app.AnalysisSettingsoptionalTab
                try
                    % Update the UI controls on Tab 2 to reflect the current settings
                    app.ManualMethodDropDown.Value = app.AnalysisSettings.Method;
                    app.ManualInnerEditField.Value = app.AnalysisSettings.InnerFactor;
                    app.ManualOuterEditField.Value = app.AnalysisSettings.OuterFactor;
                    app.RecalculateRadiiCheckBox.Value = app.AnalysisSettings.RecalculateRadii;
                catch ME
                    app.LogTextArea.Value{end+1} = ['Error updating Tab 2 UI: ' ME.message];
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'NeuroSpikeX RBIC';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.FrameSelectionTab = uitab(app.TabGroup);
            app.FrameSelectionTab.Title = '1. Frame Selection';
            app.FrameSelectionGrid = uigridlayout(app.FrameSelectionTab);
            app.Timepoint2Axes = uiaxes(app.FrameSelectionGrid);
            title(app.Timepoint2Axes, 'Time Point 2')
            xlabel(app.Timepoint2Axes, 'Time (s)')
            ylabel(app.Timepoint2Axes, 'dF/F (%)')
            app.Timepoint1Axes = uiaxes(app.FrameSelectionGrid);
            title(app.Timepoint1Axes, 'Time Point 1')
            xlabel(app.Timepoint1Axes, 'Time (s)')
            ylabel(app.Timepoint1Axes, 'dF/F (%)')
            app.SelectAndLoadButton = uibutton(app.FrameSelectionGrid, 'push');
            app.SelectAndLoadButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAndLoadButtonPushed, true);
            app.SelectAndLoadButton.Text = 'Select Timepoint Folders & Load Signals';
            app.Timepoint1EditFieldLabel = uilabel(app.FrameSelectionGrid);
            app.Timepoint1EditFieldLabel.Text = 'Timepoint 1:';
            app.Timepoint1EditField = uieditfield(app.FrameSelectionGrid, 'text');
            app.Timepoint1EditField.Editable = 'off';
            app.Timepoint2EditFieldLabel = uilabel(app.FrameSelectionGrid);
            app.Timepoint2EditFieldLabel.Text = 'Timepoint 2:';
            app.Timepoint2EditField = uieditfield(app.FrameSelectionGrid, 'text');
            app.InstructionsLabel = uilabel(app.FrameSelectionGrid);
            app.InstructionsLabel.Text = 'Instructions';
            app.AnalysisSettingsoptionalTab = uitab(app.TabGroup);
            app.AnalysisSettingsoptionalTab.Title = '2. Analysis Settings (optional)';
            app.GridLayout = uigridlayout(app.AnalysisSettingsoptionalTab);
            app.FindOptimalSettingsButton = uibutton(app.GridLayout, 'push');
            app.FindOptimalSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @FindOptimalSettingsButtonPushed, true);
            app.FindOptimalSettingsButton.Text = 'Find Optimal Background Settings';
            app.SettingsInstructionsLabel = uilabel(app.GridLayout);
            app.SettingsInstructionsLabel.Text = {'1. Complete Tab 1 to load a dataset.'; '2. Click "Find Optimal Settings" to run the optimization.'; '3. The best combination of settings will be highlighted in the table below.'};
            app.ApplyandSaveButton = uibutton(app.GridLayout, 'push');
            app.ApplyandSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyandSaveButtonPushed, true);
            app.ApplyandSaveButton.Enable = 'off';
            app.ApplyandSaveButton.Text = 'Apply & Save Optimal Settings';
            app.SettingsResultsTable = uitable(app.GridLayout);
            app.SettingsResultsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SettingsResultsTable.RowName = {};
            app.VisualizeFailedCellsButton = uibutton(app.GridLayout, 'push');
            app.VisualizeFailedCellsButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeFailedCellsButtonPushed, true);
            app.VisualizeFailedCellsButton.Enable = 'off';
            app.VisualizeFailedCellsButton.Text = 'Visualize Failed Cells';
            app.ORLabel = uilabel(app.GridLayout);
            app.ORLabel.Text = '---------------------------------------------------------------------- OR ----------------------------------------------------------------------';
            app.ManualBackgroundSubtractionMethodLabel = uilabel(app.GridLayout);
            app.ManualBackgroundSubtractionMethodLabel.Text = 'Manual Background Subtraction Method:';
            app.ManualMethodDropDown = uidropdown(app.GridLayout);
            app.ManualMethodDropDown.Items = {'Median', 'Mean'};
            app.ManualMethodDropDown.Value = 'Median';
            app.ManualOuterFactorLabel = uilabel(app.GridLayout);
            app.ManualOuterFactorLabel.Text = 'Manual Outer Factor:';
            app.ManualOuterEditField = uieditfield(app.GridLayout, 'numeric');
            app.ManualOuterEditField.Value = 2;
            app.InnerRadiusLabel = uilabel(app.GridLayout);
            app.InnerRadiusLabel.Text = 'Manual Inner Factor:';
            app.ManualInnerEditField = uieditfield(app.GridLayout, 'numeric');
            app.ManualInnerEditField.Value = 1.8;
            app.ApplyManualButton = uibutton(app.GridLayout, 'push');
            app.ApplyManualButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyManualButtonPushed, true);
            app.ApplyManualButton.Text = 'Apply & Save Manual Settings';
            app.RecalculateRadiiCheckBox = uicheckbox(app.GridLayout);
            app.RecalculateRadiiCheckBox.Text = 'Dynamically recalculate cell radii at baseline fluorescence (slower)';
            app.RecalculateRadiiCheckBox.Value = true;
            app.AnalysisPipelineTab = uitab(app.TabGroup);
            app.AnalysisPipelineTab.Title = '3. Analysis Pipeline';
            app.AnalysisGrid = uigridlayout(app.AnalysisPipelineTab);
            app.ControlAnalysisPanel = uipanel(app.AnalysisGrid);
            app.ControlAnalysisPanel.Title = 'Control Analysis';
            app.ControlAnalysisGrid = uigridlayout(app.ControlAnalysisPanel);
            app.ControlSpatialExampleAxes = uiaxes(app.ControlAnalysisGrid);
            title(app.ControlSpatialExampleAxes, 'Title')
            xlabel(app.ControlSpatialExampleAxes, 'X')
            ylabel(app.ControlSpatialExampleAxes, 'Y')
            zlabel(app.ControlSpatialExampleAxes, 'Z')
            app.ControlsDistributionAxes = uiaxes(app.ControlAnalysisGrid);
            title(app.ControlsDistributionAxes, 'Title')
            xlabel(app.ControlsDistributionAxes, 'X')
            ylabel(app.ControlsDistributionAxes, 'Y')
            zlabel(app.ControlsDistributionAxes, 'Z')
            app.SelectControlFoldersButton = uibutton(app.ControlAnalysisGrid, 'push');
            app.SelectControlFoldersButton.ButtonPushedFcn = createCallbackFcn(app, @SelectControlFoldersButtonPushed, true);
            app.SelectControlFoldersButton.Text = 'Select Control Folders';
            app.ControlsListBoxLabel = uilabel(app.ControlAnalysisGrid);
            app.ControlsListBoxLabel.Text = 'Controls:';
            app.ControlsListBox = uilistbox(app.ControlAnalysisGrid);
            app.RunControlButton = uibutton(app.ControlAnalysisGrid, 'push');
            app.RunControlButton.ButtonPushedFcn = createCallbackFcn(app, @RunControlButtonPushed, true);
            app.RunControlButton.Text = 'RUN ANALYSIS';
            app.Timepoint2SubfolderNameEditFieldLabel = uilabel(app.ControlAnalysisGrid);
            app.Timepoint2SubfolderNameEditFieldLabel.Text = 'Timepoint 2 Subfolder Name:';
            app.Timepoint2SubfolderNameEditField = uieditfield(app.ControlAnalysisGrid, 'text');
            app.Timepoint2SubfolderNameEditField.Value = '0plus';
            app.Timepoint1SubfolderNameEditFieldLabel = uilabel(app.ControlAnalysisGrid);
            app.Timepoint1SubfolderNameEditFieldLabel.Text = 'Timepoint 1 Subfolder Name:';
            app.Timepoint1SubfolderNameEditField = uieditfield(app.ControlAnalysisGrid, 'text');
            app.Timepoint1SubfolderNameEditField.Value = '0minus';
            app.ExperimentalAnalysisPanel = uipanel(app.AnalysisGrid);
            app.ExperimentalAnalysisPanel.Title = 'Experimental Analysis';
            app.ExperimentalAnalysisGrid = uigridlayout(app.ExperimentalAnalysisPanel);
            app.ExperimentalSpatialAxes = uiaxes(app.ExperimentalAnalysisGrid);
            title(app.ExperimentalSpatialAxes, 'Title')
            xlabel(app.ExperimentalSpatialAxes, 'X')
            ylabel(app.ExperimentalSpatialAxes, 'Y')
            zlabel(app.ExperimentalSpatialAxes, 'Z')
            app.ExperimentalDistributionAxes = uiaxes(app.ExperimentalAnalysisGrid);
            title(app.ExperimentalDistributionAxes, 'Title')
            xlabel(app.ExperimentalDistributionAxes, 'X')
            ylabel(app.ExperimentalDistributionAxes, 'Y')
            zlabel(app.ExperimentalDistributionAxes, 'Z')
            app.SelectExperimentalFolderButton = uibutton(app.ExperimentalAnalysisGrid, 'push');
            app.SelectExperimentalFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExperimentalFolderButtonPushed, true);
            app.SelectExperimentalFolderButton.Text = 'Select Experimental Folder';
            app.LoadControlThresholdsButton = uibutton(app.ExperimentalAnalysisGrid, 'push');
            app.LoadControlThresholdsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadControlThresholdsButtonPushed, true);
            app.LoadControlThresholdsButton.Text = 'Load Control Thresholds';
            app.RunExperimentalButton = uibutton(app.ExperimentalAnalysisGrid, 'push');
            app.RunExperimentalButton.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentalButtonPushed, true);
            app.RunExperimentalButton.Text = 'RUN ANALYSIS';
            app.ExperimentalFolderField = uieditfield(app.ExperimentalAnalysisGrid, 'text');
            app.ExperimentalFolderField.Editable = 'off';
            app.ControlThresholdsField = uieditfield(app.ExperimentalAnalysisGrid, 'text');
            app.ControlThresholdsField.Editable = 'off';
            app.Timepoint1SubfolderNameEditField_2Label = uilabel(app.ExperimentalAnalysisGrid);
            app.Timepoint1SubfolderNameEditField_2Label.Text = 'Timepoint 1 Subfolder Name:';
            app.ExpTimepoint1SubfolderNameEditField = uieditfield(app.ExperimentalAnalysisGrid, 'text');
            app.ExpTimepoint1SubfolderNameEditField.Value = '0minus';
            app.Timepoint2SubfolderNameEditField_2Label = uilabel(app.ExperimentalAnalysisGrid);
            app.Timepoint2SubfolderNameEditField_2Label.Text = 'Timepoint 2 Subfolder Name:';
            app.ExpTimepoint2SubfolderNameEditField = uieditfield(app.ExperimentalAnalysisGrid, 'text');
            app.ExpTimepoint2SubfolderNameEditField.Value = '0plus';
            app.AnalysisModeGroup = uibuttongroup(app.AnalysisGrid);
            app.AnalysisModeGroup.SelectionChangedFcn = createCallbackFcn(app, @AnalysisModeGroupSelectionChanged, true);
            app.ControlModeButton = uiradiobutton(app.AnalysisModeGroup);
            app.ControlModeButton.Text = 'Control Sample Mode';
            app.ExperimentalModeButton = uiradiobutton(app.AnalysisModeGroup);
            app.ExperimentalModeButton.Text = 'Experimental Sample Mode';
            app.ExperimentalModeButton.Value = true;
            app.LogTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RelativeBaselineChangeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end